classdef mcl_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonteCarloLocalizationUIFigure  matlab.ui.Figure
        SimulationAxis                  matlab.ui.control.UIAxes
        MonteCarloLocalizationLabel     matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        StartButton                     matlab.ui.control.Button
        xySpinnerLabel                  matlab.ui.control.Label
        Spinner_R12                     matlab.ui.control.Spinner
        thetaSpinnerLabel               matlab.ui.control.Label
        Spinner_R3                      matlab.ui.control.Spinner
        thetaSpinner_2Label             matlab.ui.control.Label
        Spinner_Q2                      matlab.ui.control.Spinner
        rSpinnerLabel                   matlab.ui.control.Label
        Spinner_Q1                      matlab.ui.control.Spinner
        UncertaintiesLabel              matlab.ui.control.Label
        MotionLabel                     matlab.ui.control.Label
        SensorLabel                     matlab.ui.control.Label
        DataAssociationSwitchLabel      matlab.ui.control.Label
        DataAssociationSwitch           matlab.ui.control.Switch
        xLabel                          matlab.ui.control.Label
        xField                          matlab.ui.control.NumericEditField
        yLabel                          matlab.ui.control.Label
        yField                          matlab.ui.control.NumericEditField
        thetaLabel                      matlab.ui.control.Label
        thetaField                      matlab.ui.control.NumericEditField
        ParticleFilterLabel             matlab.ui.control.Label
        EstimatedRobotPoseLabel         matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        Pause5sButton                   matlab.ui.control.Button
        CloseUpAxis                     matlab.ui.control.UIAxes
        Error_x_Label                   matlab.ui.control.Label
        Error_x_Field                   matlab.ui.control.NumericEditField
        Error_y_Label                   matlab.ui.control.Label
        Error_y_Field                   matlab.ui.control.NumericEditField
        Error_theta_Label               matlab.ui.control.Label
        Error_theta_Field               matlab.ui.control.NumericEditField
        ErrorLabel                      matlab.ui.control.Label
        OutlierDetectionLabel           matlab.ui.control.Label
        OutlierSwitch                   matlab.ui.control.Switch
        OutlierSpinner                  matlab.ui.control.Spinner
        MeasurementsLabel               matlab.ui.control.Label
        MeasurementField                matlab.ui.control.NumericEditField
        n_measurementsLabel             matlab.ui.control.Label
        IncorrectField                  matlab.ui.control.NumericEditField
        incorrectlyclassifiedLabel      matlab.ui.control.Label
        OutlierField                    matlab.ui.control.NumericEditField
        outliersdetectedLabel           matlab.ui.control.Label
        CheckBoxes                      matlab.ui.container.ButtonGroup
        GroundTruth_CheckBox            matlab.ui.control.CheckBox
        Measurement_CheckBox            matlab.ui.control.CheckBox
        Odometry_CheckBox               matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        estimatedposeLabel              matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        groundtruthposeLabel            matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        odometryposeLabel               matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        correctassociationLabel         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        incorrectassociationLabel       matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        outlierLabel                    matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        ParticlesSliderLabel            matlab.ui.control.Label
        ParticlesSlider                 matlab.ui.control.Slider
        ParticleEditField               matlab.ui.control.NumericEditField
        GlobalTrackingSwitch            matlab.ui.control.Switch
        ButtonGroup                     matlab.ui.container.ButtonGroup
        OffButton                       matlab.ui.control.RadioButton
        MultinomialButton               matlab.ui.control.RadioButton
        SystematicButton                matlab.ui.control.RadioButton
        SnapshotButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        simulationfile % name of file containing simulation data
        mapfile % name of file containing landmark coordinates
    end
    
    methods (Access = private)
        
        function reset_app(app)
            % clear all figures
            cla(app.SimulationAxis);
            cla(app.CloseUpAxis);
            title(app.SimulationAxis, 'Simulation Time');
            % enable dataset selection
            app.DropDown.Enable = 'on';
            app.DropDown.Value = 'select_dataset';
            % disable simulation buttons
            app.StartButton.Enable = 'off';
            app.Pause5sButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            % disable outlier detection
            app.OutlierSwitch.Value = 'Off';
            app.OutlierSpinner.Visible = 'off';
            app.OutlierSwitch.Enable = 'off';
            % disable snapshot button
            app.SnapshotButton.Enable = 'off';
            % set uncertainty spinners to 0 and disable them
            app.Spinner_R12.Value = 0.01;
            app.Spinner_R3.Value = 1;
            app.Spinner_Q2.Value = 1;
            app.Spinner_Q1.Value = 0.01;
            app.Spinner_R12.Enable = 'off';
            app.Spinner_R3.Enable = 'off';
            app.Spinner_Q1.Enable = 'off';
            app.Spinner_Q2.Enable = 'off';
            % disable default button
            app.DefaultButton.Enable = 'off';
            % set options switches to off and disable them
            app.DataAssociationSwitch.Value = 'Off';
            app.DataAssociationSwitch.Enable = 'off';
            app.GlobalTrackingSwitch.Enable = 'off';
            app.ParticlesSlider.Enable = 'off';
            app.ParticleEditField.Enable = 'off';
            app.GlobalTrackingSwitch.Enable = 'off';
            app.MultinomialButton.Enable = 'off';
            app.SystematicButton.Enable = 'off';
            app.OffButton.Enable = 'off';
            % set all fields to 0
            app.MeasurementField.Value = 0;
            app.IncorrectField.Value = 0;
            app.OutlierField.Value = 0;
            app.xField.Value = 0;
            app.yField.Value = 0;
            app.thetaField.Value = 0;
            app.Error_x_Field.Value = 0;
            app.Error_y_Field.Value = 0;
            app.Error_theta_Field.Value = 0;
            % set all verbose check boxes to true but disable them
            app.Measurement_CheckBox.Enable = 'off';
            app.GroundTruth_CheckBox.Enable = 'off';
            app.Odometry_CheckBox.Enable = 'off';
            app.Measurement_CheckBox.Value = true;
            app.GroundTruth_CheckBox.Value = true;
            app.Odometry_CheckBox.Value = true;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if strcmp(app.DropDown.Value, 'select_dataset')
                % always reset app if no dataset selected
                reset_app(app);
            else
                % set simulation file to selected dataset
                app.simulationfile = app.DropDown.Value;
                % extract dataset ID from filename
                dataset_id = app.simulationfile(end-4);
                % set mapfile to corresponding file
                app.mapfile = strcat('map', dataset_id, '.txt');
                % initialize GUI for selected dataset
                init_app(app, 'C:\Users\Alexa\Desktop\KTH\årskurs_5\Applied Estimation\Applied-Estimation\2 - Particle Filter\MC_Localization\Simulation\DataSets/', app.mapfile, str2num(dataset_id))
                % enable start button
                app.StartButton.Enable = 'on';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % enable stop, pause and snapshot button, disable start button and
            % dataset selector. Run simulation
            app.Pause5sButton.Enable = 'on';
            app.StopButton.Enable = 'on';
            app.SnapshotButton.Enable = 'on';
            app.StartButton.Enable = 'off';
            app.DropDown.Enable= 'off';
            app.GlobalTrackingSwitch.Enable = 'off';
            app.ParticlesSlider.Enable = 'off';
            %run simulation            
            run_simulation(app, 'C:\Users\Alexa\Desktop\KTH\årskurs_5\Applied Estimation\Applied-Estimation\2 - Particle Filter\MC_Localization\Simulation\DataSets/', app.simulationfile)
        end

        % Value changed function: Spinner_R12
        function Spinner_R12ValueChanged(app, event)
            global R
            % update motion model covariance matrix
            R(1,1) = app.Spinner_R12.Value;
            R(2,2) = app.Spinner_R12.Value;
        end

        % Value changed function: Spinner_R3
        function Spinner_R3ValueChanged(app, event)
            global R
            % update motion model covariance matrix
            R(3,3) = app.Spinner_R3.Value / 360 * (2*pi);
        end

        % Value changed function: Spinner_Q2
        function Spinner_Q2ValueChanged(app, event)
            global Q
            % update measurement update covariance matrix
            Q(2,2) = app.Spinner_Q2.Value / 360 * (2*pi);
        end

        % Value changed function: Spinner_Q1
        function Spinner_Q1ValueChanged(app, event)
            global Q
            % update measurement update covariance matrix
            Q(1,1) = app.Spinner_Q1.Value;  
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % set stop_execution flag so simulation is stopped at the end of the iteration    
            global stop_execution
            stop_execution = 1;
            % reset app for new simulation
            reset_app(app);
            % if simulation finished without being interrupted, rename
            % button from "end" to "stop"
            if strcmp(app.StopButton.Text, 'End')
                app.StopButton.Text = 'Stop';
                app.ErrorLabel.Text = 'Error';
            end
        end

        % Button pushed function: Pause5sButton
        function Pause5sButtonPushed(app, event)
            pause(5);
        end

        % Value changed function: GroundTruth_CheckBox
        function GroundTruth_CheckBoxValueChanged(app, event)
            global show_ground_truth
            show_ground_truth = app.GroundTruth_CheckBox.Value;
        end

        % Value changed function: Measurement_CheckBox
        function Measurement_CheckBoxValueChanged(app, event)
            global show_measurements
            show_measurements = app.Measurement_CheckBox.Value;
        end

        % Value changed function: Odometry_CheckBox
        function Odometry_CheckBoxValueChanged(app, event)
            global show_odometry
            show_odometry = app.Odometry_CheckBox.Value;
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            % reset all uncertainty and outlier detection parameters to
            % default values for currently selected dataset
            global default_R
            global default_Q
            global default_lambda_psi
            global R
            global Q
            global lambda_psi
            global DATA_ASSOCIATION
            global BATCH_UPDATE  
            DATA_ASSOCIATION = 'On'; 
            BATCH_UPDATE = 'On';
            R = default_R;
            Q = default_Q;
            lambda_psi = default_lambda_psi;
            % display values in Spinners
            app.Spinner_R12.Value = sqrt(R(1, 1));
            app.Spinner_R3.Value = round(sqrt(R(3, 3)) / (2*pi) * 360);
            app.Spinner_Q1.Value = sqrt(Q(1, 1));
            app.Spinner_Q2.Value = round(sqrt(Q(2, 2)) / (2*pi) * 360);
            % update outlier detection display
            if lambda_psi == 0
                app.OutlierSpinner.Visible = false;
                app.OutlierSwitch.Value = 'Off';
            else
                app.OutlierSpinner.Visible = true;
                app.OutlierSwitch.Value = 'On';
                app.OutlierSpinner.Value = lambda_psi;
            end
            % enable batch update and data association
            
            app.DataAssociationSwitch.Value = 'On';
        end

        % Value changed function: OutlierSwitch
        function OutlierSwitchValueChanged(app, event)
            global lambda_psi;
            global default_lambda_psi;
            if strcmp(app.OutlierSwitch.Value, 'On')
                app.OutlierSpinner.Visible = true;
                lambda_psi = default_lambda_psi;
                app.OutlierSpinner.Value = lambda_psi;
            else
                app.OutlierSpinner.Visible = false;
                lambda_psi = 0;
            end
        end

        % Value changed function: OutlierSpinner
        function OutlierSpinnerValueChanged(app, event)
            global lambda_psi
            lambda_psi = app.OutlierSpinner.Value;
            if lambda_psi == 0
                app.OutlierSpinner.Visible = false;
                app.OutlierSwitch.Value = 'Off';
            end
        end

        % Value changed function: DataAssociationSwitch
        function DataAssociationSwitchValueChanged(app, event)
            global DATA_ASSOCIATION
            DATA_ASSOCIATION = app.DataAssociationSwitch.Value;
        end

        % Value changed function: ParticlesSlider
        function ParticlesSliderValueChanged(app, event)
            global M
            M = round(app.ParticlesSlider.Value);
            app.ParticleEditField.Value = M;
        end

        % Value changed function: GlobalTrackingSwitch
        function GlobalTrackingSwitchValueChanged(app, event)
            global global_localization
            global lambda_psi;
            global default_lambda_psi;
            if strcmp(app.GlobalTrackingSwitch.Value, 'Global')
                global_localization = 1;
                app.OutlierSwitch.Value = 'Off';
                app.OutlierSpinner.Visible = false;
                lambda_psi = 0;
            else
                global_localization = 0;
                app.OutlierSwitch.Value = 'On';
                app.OutlierSpinner.Visible = true;
                lambda_psi = default_lambda_psi;
                app.OutlierSpinner.Value = lambda_psi;
                
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            global RESAMPLE_MODE
            sample_strategy = app.ButtonGroup.SelectedObject.Text;
            if strcmp(sample_strategy, 'Off')
                RESAMPLE_MODE = 0;
            elseif strcmp(sample_strategy, 'Multinomial')
                RESAMPLE_MODE = 1;
            else
                RESAMPLE_MODE = 2;
            end
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            global t 
            global R
            global Q
            global lambda_psi
            % create a temporary figure with axes.
            snap_fig = figure('Resize', 'off');
            snap_axes = axes(snap_fig);
            % set title
            title(snap_axes, sprintf('Simulation Time: %.1f s', round(t, 1)));
            snap_axes.Title.FontName = app.SimulationAxis.Title.FontName;
            snap_axes.Title.FontSize = app.SimulationAxis.Title.FontSize;
            % copy all SimulationAxis children, take over axes limits and aspect ratio.            
            allChildren = app.SimulationAxis.XAxis.Parent.Children;
            copyobj(allChildren, snap_axes)
            snap_axes.XLim = app.SimulationAxis.XLim;
            snap_axes.YLim = app.SimulationAxis.YLim;
            snap_axes.TickLength = app.SimulationAxis.TickLength;
            snap_axes.FontName = app.SimulationAxis.FontName;
            snap_axes.FontSize = app.SimulationAxis.FontSize;
            snap_axes.Box = 'on';
            snap_axes.XTick = app.SimulationAxis.XTick;
            snap_axes.YTick = app.SimulationAxis.YTick;
            % position axis within figure
            outerpos = snap_axes.OuterPosition;
            top_margin = snap_axes.TightInset(4); 
            ax_height = outerpos(4) - top_margin;
            snap_axes.Position = [outerpos(1) outerpos(2) outerpos(3) ax_height];
            relative_height = snap_axes.Position(4); % relative height of axis w.r.t figure
            % adjust YLim to make space for legend
            y_span_old = snap_axes.YLim(2) - snap_axes.YLim(1);
            relative_y_lim_max_increase = 0.20; % relative increase of y-axis maximum
            snap_axes.YLim(2) = (1+relative_y_lim_max_increase) * snap_axes.YLim(2);
            y_span_new = snap_axes.YLim(2) - snap_axes.YLim(1);
            relative_height_increase = (y_span_new / y_span_old) - 1; % relative increase of axis size
            box_height_axis = relative_height_increase / (1+relative_height_increase); % percentage of axis height free for box
            relative_box_height = box_height_axis*relative_height; % relative height of box w.r.t. figure
            relative_box_width = 0.375;
            box_dim = [(1-relative_box_width)/2, relative_height-relative_box_height, relative_box_width, relative_box_height*0.85];
            % print standard deviations of motion and observation model and
            % outlier detection information
            str1 = strcat('\textbf{Std Motion Model}: x:\thinspace', num2str(round(sqrt(R(1, 1)), 3)), ' y:\thinspace', num2str(round(sqrt(R(2, 2)), 3)), ' $\theta$:\thinspace', num2str(round(sqrt(R(3, 3)), 3)));
            str2 = strcat('\textbf{Std Observation Model:} r:\thinspace', num2str(round(sqrt(Q(1, 1)), 3)), ' $\theta$:\thinspace', num2str(round(sqrt(Q(2, 2)), 3)));
            if lambda_psi == 0
                str3 = '\textbf{Outlier Detection} Off';
            else
                str3 = strcat('\textbf{Outlier Detection} On, $\lambda_{\psi}$:\thinspace', num2str(lambda_psi));
            end
            str = char(str1, str2, str3);
            % create annotation box with specified information
            box = annotation('textbox', box_dim, 'Interpreter', 'latex', 'String', str);
            % place new figure on top of GUI
            WinOnTop(snap_fig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MonteCarloLocalizationUIFigure = uifigure('Visible', 'off');
            app.MonteCarloLocalizationUIFigure.Name = 'Applied Estimation | Lab 2';
            app.SimulationAxis = uiaxes(app.MonteCarloLocalizationUIFigure);
            title(app.SimulationAxis, 'Simulation Time')
            app.SimulationAxis.XTick = [];
            app.SimulationAxis.YTick = [];
            app.MonteCarloLocalizationLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.MonteCarloLocalizationLabel.Text = 'Monte Carlo Localization';
            app.DropDown = uidropdown(app.MonteCarloLocalizationUIFigure);
            app.DropDown.Items = {'Select Dataset', 'Dataset 1', 'Dataset 2', 'Dataset 3', 'Dataset 4', 'Dataset 5'};
            app.DropDown.ItemsData = {'select_dataset', 'dataset1.txt', 'dataset2.txt', 'dataset3.txt', 'dataset4.txt', 'dataset5.txt'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'select_dataset';
            app.StartButton = uibutton(app.MonteCarloLocalizationUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.xySpinnerLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.xySpinnerLabel.Text = 'x, y';
            app.Spinner_R12 = uispinner(app.MonteCarloLocalizationUIFigure);
            app.Spinner_R12.Step = 0.01;
            app.Spinner_R12.Limits = [0.01 10];
            app.Spinner_R12.ValueDisplayFormat = '%.2fm';
            app.Spinner_R12.ValueChangedFcn = createCallbackFcn(app, @Spinner_R12ValueChanged, true);
            app.Spinner_R12.Enable = 'off';
            app.Spinner_R12.Value = 0.01;
            app.thetaSpinnerLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.thetaSpinnerLabel.Text = 'theta';
            app.Spinner_R3 = uispinner(app.MonteCarloLocalizationUIFigure);
            app.Spinner_R3.Limits = [1 360];
            app.Spinner_R3.ValueDisplayFormat = '%d°';
            app.Spinner_R3.ValueChangedFcn = createCallbackFcn(app, @Spinner_R3ValueChanged, true);
            app.Spinner_R3.Enable = 'off';
            app.Spinner_R3.Value = 1;
            app.thetaSpinner_2Label = uilabel(app.MonteCarloLocalizationUIFigure);
            app.thetaSpinner_2Label.Text = 'theta';
            app.Spinner_Q2 = uispinner(app.MonteCarloLocalizationUIFigure);
            app.Spinner_Q2.Limits = [1 360];
            app.Spinner_Q2.ValueDisplayFormat = '%d°';
            app.Spinner_Q2.ValueChangedFcn = createCallbackFcn(app, @Spinner_Q2ValueChanged, true);
            app.Spinner_Q2.Enable = 'off';
            app.Spinner_Q2.Value = 1;
            app.rSpinnerLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.rSpinnerLabel.Text = 'r';
            app.Spinner_Q1 = uispinner(app.MonteCarloLocalizationUIFigure);
            app.Spinner_Q1.Step = 0.01;
            app.Spinner_Q1.Limits = [0.01 10];
            app.Spinner_Q1.ValueDisplayFormat = '%.2fm';
            app.Spinner_Q1.ValueChangedFcn = createCallbackFcn(app, @Spinner_Q1ValueChanged, true);
            app.Spinner_Q1.Enable = 'off';
            app.Spinner_Q1.Value = 0.01;
            app.UncertaintiesLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.UncertaintiesLabel.Text = 'Uncertainties';
            app.MotionLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.MotionLabel.Text = 'Motion ';
            app.SensorLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.SensorLabel.Text = 'Sensor';
            app.DataAssociationSwitchLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.DataAssociationSwitchLabel.Text = 'Data Association';
            app.DataAssociationSwitch = uiswitch(app.MonteCarloLocalizationUIFigure, 'slider');
            app.DataAssociationSwitch.ValueChangedFcn = createCallbackFcn(app, @DataAssociationSwitchValueChanged, true);
            app.DataAssociationSwitch.Enable = 'off';
            app.xLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.xLabel.Text = 'x';
            app.xField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.xField.ValueDisplayFormat = '%.2fm';
            app.xField.Editable = 'off';
            app.yLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.yLabel.Text = 'y';
            app.yField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.yField.ValueDisplayFormat = '%.2fm';
            app.yField.Editable = 'off';
            app.thetaLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.thetaLabel.Text = 'theta';
            app.thetaField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.thetaField.ValueDisplayFormat = '%d°';
            app.thetaField.Editable = 'off';
            app.ParticleFilterLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.ParticleFilterLabel.Text = 'Particle Filter';
            app.EstimatedRobotPoseLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.EstimatedRobotPoseLabel.Text = 'Estimated Robot Pose';
            app.StopButton = uibutton(app.MonteCarloLocalizationUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.Pause5sButton = uibutton(app.MonteCarloLocalizationUIFigure, 'push');
            app.Pause5sButton.ButtonPushedFcn = createCallbackFcn(app, @Pause5sButtonPushed, true);
            app.Pause5sButton.Enable = 'off';
            app.Pause5sButton.Text = 'Pause (5s)';
            app.CloseUpAxis = uiaxes(app.MonteCarloLocalizationUIFigure);
            app.CloseUpAxis.XLim = [-0.5 0.5];
            app.CloseUpAxis.YLim = [-0.5 0.5];
            app.CloseUpAxis.XTick = [];
            app.CloseUpAxis.XTickLabel = '';
            app.CloseUpAxis.YTick = [];
            app.CloseUpAxis.YTickLabel = '';
            app.Error_x_Label = uilabel(app.MonteCarloLocalizationUIFigure);
            app.Error_x_Label.Text = 'x';
            app.Error_x_Field = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.Error_x_Field.ValueDisplayFormat = '%.2fm';
            app.Error_x_Field.Editable = 'off';
            app.Error_y_Label = uilabel(app.MonteCarloLocalizationUIFigure);
            app.Error_y_Label.Text = 'y';
            app.Error_y_Field = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.Error_y_Field.ValueDisplayFormat = '%.2fm';
            app.Error_y_Field.Editable = 'off';
            app.Error_theta_Label = uilabel(app.MonteCarloLocalizationUIFigure);
            app.Error_theta_Label.Text = 'theta';
            app.Error_theta_Field = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.Error_theta_Field.ValueDisplayFormat = '%d°';
            app.Error_theta_Field.Editable = 'off';
            app.ErrorLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.ErrorLabel.Text = 'Error';
            app.OutlierDetectionLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.OutlierDetectionLabel.Text = 'Outlier Detection';
            app.OutlierSwitch = uiswitch(app.MonteCarloLocalizationUIFigure, 'slider');
            app.OutlierSwitch.ValueChangedFcn = createCallbackFcn(app, @OutlierSwitchValueChanged, true);
            app.OutlierSwitch.Enable = 'off';
            app.OutlierSpinner = uispinner(app.MonteCarloLocalizationUIFigure);
            app.OutlierSpinner.Step = 0.5;
            app.OutlierSpinner.Limits = [0 30];
            app.OutlierSpinner.ValueChangedFcn = createCallbackFcn(app, @OutlierSpinnerValueChanged, true);
            app.OutlierSpinner.Editable = 'off';
            app.OutlierSpinner.Enable = 'off';
            app.OutlierSpinner.Value = 2;
            app.MeasurementsLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.MeasurementsLabel.Text = 'Measurements';
            app.MeasurementField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.MeasurementField.ValueDisplayFormat = '%d';
            app.MeasurementField.Editable = 'off';
            app.n_measurementsLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.n_measurementsLabel.Text = 'number of measurements';
            app.IncorrectField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.IncorrectField.ValueDisplayFormat = '%d';
            app.IncorrectField.Editable = 'off';
            app.incorrectlyclassifiedLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.incorrectlyclassifiedLabel.Text = 'incorrectly classified';
            app.OutlierField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.OutlierField.ValueDisplayFormat = '%d';
            app.OutlierField.Editable = 'off';
            app.outliersdetectedLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.outliersdetectedLabel.Text = 'outliers detected';
            app.CheckBoxes = uibuttongroup(app.MonteCarloLocalizationUIFigure);
            app.GroundTruth_CheckBox = uicheckbox(app.CheckBoxes);
            app.GroundTruth_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GroundTruth_CheckBoxValueChanged, true);
            app.GroundTruth_CheckBox.Enable = 'off';
            app.GroundTruth_CheckBox.Text = 'show ground truth';
            app.GroundTruth_CheckBox.Value = true;
            app.Measurement_CheckBox = uicheckbox(app.CheckBoxes);
            app.Measurement_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Measurement_CheckBoxValueChanged, true);
            app.Measurement_CheckBox.Enable = 'off';
            app.Measurement_CheckBox.Text = 'show measurements';
            app.Measurement_CheckBox.Value = true;
            app.Odometry_CheckBox = uicheckbox(app.CheckBoxes);
            app.Odometry_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Odometry_CheckBoxValueChanged, true);
            app.Odometry_CheckBox.Enable = 'off';
            app.Odometry_CheckBox.Text = 'show odometry';
            app.Odometry_CheckBox.Value = true;
            app.Panel = uipanel(app.MonteCarloLocalizationUIFigure);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.estimatedposeLabel = uilabel(app.Panel);
            app.estimatedposeLabel.Text = 'estimated pose';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.groundtruthposeLabel = uilabel(app.Panel);
            app.groundtruthposeLabel.Text = 'ground truth pose';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '';
            app.odometryposeLabel = uilabel(app.Panel);
            app.odometryposeLabel.Text = 'odometry pose';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '';
            app.correctassociationLabel = uilabel(app.Panel);
            app.correctassociationLabel.Text = 'correct association';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '';
            app.incorrectassociationLabel = uilabel(app.Panel);
            app.incorrectassociationLabel.Text = 'incorrect association';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '';
            app.outlierLabel = uilabel(app.Panel);
            app.outlierLabel.Text = 'outlier';
            app.DefaultButton = uibutton(app.MonteCarloLocalizationUIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Enable = 'off';
            app.DefaultButton.Text = 'Default';
            app.ParticlesSliderLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.ParticlesSliderLabel.Text = 'Particles';
            app.ParticlesSlider = uislider(app.MonteCarloLocalizationUIFigure);
            app.ParticlesSlider.Limits = [0 10000];
            app.ParticlesSlider.MajorTicks = [0 5000 10000];
            app.ParticlesSlider.MajorTickLabels = {'0', '5.000', '10.000'};
            app.ParticlesSlider.ValueChangedFcn = createCallbackFcn(app, @ParticlesSliderValueChanged, true);
            app.ParticlesSlider.MinorTicks = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000];
            app.ParticlesSlider.Enable = 'off';
            app.ParticlesSlider.Value = 1000;
            app.ParticleEditField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.ParticleEditField.Editable = 'off';
            app.ParticleEditField.Enable = 'off';
            app.GlobalTrackingSwitch = uiswitch(app.MonteCarloLocalizationUIFigure, 'slider');
            app.GlobalTrackingSwitch.Items = {'Global', 'Tracking'};
            app.GlobalTrackingSwitch.ValueChangedFcn = createCallbackFcn(app, @GlobalTrackingSwitchValueChanged, true);
            app.GlobalTrackingSwitch.Enable = 'off';
            app.GlobalTrackingSwitch.Value = 'Global';
            app.ButtonGroup = uibuttongroup(app.MonteCarloLocalizationUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.OffButton = uiradiobutton(app.ButtonGroup);
            app.OffButton.Enable = 'off';
            app.OffButton.Text = 'Off';
            app.MultinomialButton = uiradiobutton(app.ButtonGroup);
            app.MultinomialButton.Enable = 'off';
            app.MultinomialButton.Text = 'Multinomial';
            app.SystematicButton = uiradiobutton(app.ButtonGroup);
            app.SystematicButton.Enable = 'off';
            app.SystematicButton.Text = 'Systematic';
            app.SystematicButton.Value = true;
            app.SnapshotButton = uibutton(app.MonteCarloLocalizationUIFigure, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Icon = 'camera_icon.png';
            app.SnapshotButton.Enable = 'off';
            app.SnapshotButton.Text = '';
            % Show the figure after all components are created
            app.MonteCarloLocalizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mcl_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonteCarloLocalizationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonteCarloLocalizationUIFigure)
        end
    end
end